classdef SurfaceNormalVectorCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        GridLayout10          matlab.ui.container.GridLayout
        SyncButton            matlab.ui.control.Button
        AdjustButton          matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        CalculateButton       matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        GridLayout8           matlab.ui.container.GridLayout
        GridLayout9           matlab.ui.container.GridLayout
        EditField             matlab.ui.control.NumericEditField
        AngleLabel            matlab.ui.control.Label
        DirectionButtonGroup  matlab.ui.container.ButtonGroup
        anticlockwiseButton   matlab.ui.control.RadioButton
        clockwiseButton       matlab.ui.control.RadioButton
        DropDown_2            matlab.ui.control.DropDown
        RotaionaxisLabel      matlab.ui.control.Label
        GridLayout6           matlab.ui.container.GridLayout
        GridLayout7           matlab.ui.container.GridLayout
        SelectButton          matlab.ui.control.Button
        OpenButton            matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        RightPanel            matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        OriginalTab           matlab.ui.container.Tab
        GridLayout3           matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        RotatedTab            matlab.ui.container.Tab
        GridLayout4           matlab.ui.container.GridLayout
        UIAxes2               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PhototFlag = false(1,2);
        Photos = cell(1,2);
        SelectionFlag = false(1,2);
        ScattersNumber = [0,0]
        Scatters = cell(2,4);
        Labels = cell(2,4);
        Coords = zeros(4,4);
        TextList = {'A','B','C'};
        CurrentAxe = 1
        Key = 'w'
        KeyState = false
        AdjustPoint = zeros(2,2)
        TableData
        Lines = cell(1,2);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.UIFigure.Name = ...
                'Surface Normal Vector Calculator';
            app.OpenButton.ButtonPushedFcn = {@OpenImageButtonPushedFcn,app};
            app.SelectButton.ButtonPushedFcn = {@SelectPointsButtonPushedFcn,app};
            app.CalculateButton.ButtonPushedFcn = {@CalculateButtonPushedFcn,app};
            app.ClearButton.ButtonPushedFcn = {@ClearButtonPushedFcn,app};
            app.AdjustButton.ButtonPushedFcn = {@AdjustButtonPushedFcn,app};
            app.SyncButton.ButtonPushedFcn = {@SyncButtonPushedFcn,app};
            
            app.DropDown.ValueChangedFcn = {@DropDownValueChanged,app};
            app.TabGroup.SelectionChangedFcn = {@TabGroupSelectionChanged,app};
            app.UIFigure.KeyPressFcn = {@UIFigureKeyPress,app};
            app.UIFigure.KeyReleaseFcn = {@UIfigureKeyRelease,app};
            
            app.UITable.ColumnName = {'X';'Y';'Z'}; 
            app.UITable.RowName = {'A';'B';'C';'AB';'BC';'N';'Angle'};
            app.UITable.Data = repmat({[]},7,3);
            app.TableData = {app.UITable.Data,app.UITable.Data};
            app.UITable.ColumnWidth = 'fit';
            
%             app.UIAxes.Toolbar.SelectionChangedFcn={@fun,app};
            app.UIAxes.NextPlot = 'add';
            app.UIAxes2.NextPlot = 'add';
            axis(app.UIAxes,'image')
            axis(app.UIAxes2,'image')
            app.UIAxes.Visible = 'off'; 
            app.UIAxes2.Visible = 'off';
            app.UIAxes.PickableParts = 'all';
            app.UIAxes2.PickableParts = 'all';
            app.UIAxes.YDir = 'reverse';
            app.UIAxes2.YDir = 'reverse';
        end

        % Callback function
        function MenuSelected(app, event)
            a=1;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value<=0 || value>=90
                uialert(app.UIFigure,'ANgle should be in 0~90 degree','Wrong Value')
                app.EditField.Value = event.PreviousValue;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {550, 550};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {195, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.DropDown = uidropdown(app.GridLayout6);
            app.DropDown.Items = {'Orignial', 'Rotated'};
            app.DropDown.Value = 'Orignial';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.OpenButton = uibutton(app.GridLayout7, 'push');
            app.OpenButton.Text = 'Open';
            app.SelectButton = uibutton(app.GridLayout7, 'push');
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.RotaionaxisLabel = uilabel(app.GridLayout8);
            app.RotaionaxisLabel.Text = 'Rotaion axis';
            app.DropDown_2 = uidropdown(app.GridLayout8);
            app.DropDown_2.Items = {'Horizontal', 'Vertical'};
            app.DropDown_2.Value = 'Horizontal';
            app.DirectionButtonGroup = uibuttongroup(app.GridLayout8);
            app.DirectionButtonGroup.Title = 'Direction';
            app.clockwiseButton = uiradiobutton(app.DirectionButtonGroup);
            app.clockwiseButton.Text = 'clockwise';
            app.clockwiseButton.Value = true;
            app.anticlockwiseButton = uiradiobutton(app.DirectionButtonGroup);
            app.anticlockwiseButton.Text = 'anticlockwise';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.AngleLabel = uilabel(app.GridLayout9);
            app.AngleLabel.Text = 'Angle/ °';
            app.EditField = uieditfield(app.GridLayout9, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 20;
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.CalculateButton = uibutton(app.GridLayout10, 'push');
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.GridLayout10, 'push');
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.AdjustButton = uibutton(app.GridLayout10, 'push');
            app.AdjustButton.Enable = 'off';
            app.AdjustButton.Text = 'Adjust';
            app.SyncButton = uibutton(app.GridLayout10, 'push');
            app.SyncButton.Enable = 'off';
            app.SyncButton.Text = 'Sync';
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.OriginalTab = uitab(app.TabGroup);
            app.OriginalTab.Title = 'Original';
            app.GridLayout3 = uigridlayout(app.OriginalTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes, 'Z')
            app.RotatedTab = uitab(app.TabGroup);
            app.RotatedTab.Title = 'Rotated';
            app.GridLayout4 = uigridlayout(app.RotatedTab);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SurfaceNormalVectorCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end